classdef nanocube_vdW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CubeconfigurationPanel          matlab.ui.container.Panel
        thLabel                         matlab.ui.control.Label
        slider_theta                    matlab.ui.control.Slider
        dsLabel                         matlab.ui.control.Label
        slider_ds                       matlab.ui.control.Slider
        dyLabel                         matlab.ui.control.Label
        slider_dy                       matlab.ui.control.Slider
        dzLabel                         matlab.ui.control.Label
        slider_dz                       matlab.ui.control.Slider
        phLabel                         matlab.ui.control.Label
        slider_phi                      matlab.ui.control.Slider
        psLabel                         matlab.ui.control.Label
        slider_psi                      matlab.ui.control.Slider
        PositionLabel                   matlab.ui.control.Label
        OrientationLabel                matlab.ui.control.Label
        ComputeatomisticButton          matlab.ui.control.Button
        ComputeanalyticalButton         matlab.ui.control.Button
        EditField_phi                   matlab.ui.control.NumericEditField
        EditField_theta                 matlab.ui.control.NumericEditField
        EditField_psi                   matlab.ui.control.NumericEditField
        EditField_ds                    matlab.ui.control.NumericEditField
        EditField_dy                    matlab.ui.control.NumericEditField
        EditField_dz                    matlab.ui.control.NumericEditField
        MaterialparametersLabel         matlab.ui.control.Label
        CubesidelengthnmEditFieldLabel  matlab.ui.control.Label
        CubesidelengthnmEditField       matlab.ui.control.NumericEditField
        Hamakerconstant10E19JEditFieldLabel  matlab.ui.control.Label
        Hamakerconstant10E19JEditField  matlab.ui.control.NumericEditField
        EnterparametersButton           matlab.ui.control.Button
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        AtomicdiameternmEditFieldLabel  matlab.ui.control.Label
        AtomicdiameternmEditField       matlab.ui.control.NumericEditField
        Densitygcm3EditFieldLabel       matlab.ui.control.Label
        Densitygcm3EditField            matlab.ui.control.NumericEditField
        CODE_NAME                       matlab.ui.control.DropDown
        AtomicweightDaEditFieldLabel    matlab.ui.control.Label
        AtomicweightDaEditField         matlab.ui.control.NumericEditField
        LJenergyparameterkcalmolLabel   matlab.ui.control.Label
        LJenergyparameterEditField      matlab.ui.control.NumericEditField
        plot_cubevis                    matlab.ui.control.UIAxes
        plot_energy                     matlab.ui.control.UIAxes
        plot_face1                      matlab.ui.control.UIAxes
        plot_face3                      matlab.ui.control.UIAxes
        StatusLabel                     matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        ExportanalyticalresultButton    matlab.ui.control.Button
        ExportatomisticresultButton     matlab.ui.control.Button
        ExportfilenameLabel             matlab.ui.control.Label
        ExportfilenameTextArea          matlab.ui.control.TextArea
        ShortrangeattractionLabel       matlab.ui.control.Label
        ShortrangeattractionTextArea    matlab.ui.control.TextArea
        LongrangeattractionLabel        matlab.ui.control.Label
        LongrangeattractionTextArea     matlab.ui.control.TextArea
        RepulsionTextAreaLabel          matlab.ui.control.Label
        RepulsionTextArea               matlab.ui.control.TextArea
        LJenergyparameterLabel          matlab.ui.control.Label
        LJenergyparameterTextArea       matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        CPUTimeatomcalcTextAreaLabel    matlab.ui.control.Label
        CPUTimeatomcalcTextArea         matlab.ui.control.TextArea
        Image2                          matlab.ui.control.Image
        PositionLabel_2                 matlab.ui.control.Label
        OrientationLabel_2              matlab.ui.control.Label
        ExportpathTextAreaLabel         matlab.ui.control.Label
        ExportpathTextArea              matlab.ui.control.TextArea
        EnergycurrentconfigLabel        matlab.ui.control.Label
        EnergycurrentconfigTextArea     matlab.ui.control.TextArea
    end


    properties (Access = public)
        lc=12.5;     
        facewindow=20;
        numdist=200;
        sig=1.0;
        eps=1.0;
        ds_calc=[1.0; 1.5; 2.0; 3.0; 5.0; 7.5; 10.0];
        catr1=-7.75596;
        atre1=3.4339;
        catr2=-4.65093;
        atre2=2.7167;
        crep=3.85898;
        repe=10.66482;   
        plot_aa1;
        plot_aa2;
        plot_aa3;
        plot_aa1far;
        plot_aa2far;
        plot_aa3far;
        plot_gen1;
        plot_gen2;
        plot_gen3;
        plot_dat;
        distcutgen=24;
        disttotgen=38;
        distcutatom=8;
        disttotatom=15;
    end

    methods (Access = private)
        %Use parameters c,n for D=50σ cubes
        function paramdefault(app)
            app.catr1=-7.75596;
            app.atre1=3.4339;
            app.catr2=-4.65093;
            app.atre2=2.7167;
            app.crep=3.85898;
            app.repe=10.66482;            
        end
        
        %Obtain parameters c,n for the specified cube size.
        %Urod is computed and fitted with power-law functions.
        function paramcalc(app)
            lc=app.lc;
            lc2=lc*2;
            distcut1=2.0;
            distcut2=lc2*0.1;
            sig=app.sig;
            nlc2=floor(lc2/sig);
            for i1=1:41
                ds(i1,1)=1+(i1-1)*0.1;
            end
            
            nind=cast((distcut2-5)/0.5,'uint8');
            
            for i1=1:nind
                ds(41+i1,1)=5+i1*0.5;
            end
            ds=ds*sig;
            ndist=nind+41;
            
            iatom=0;
            stpos=-lc+sig/2.0;
            sig6=sig^6;
            sig12=sig^12;
            uatr=0;
            urep=0;
            utot=0;
            iatom=0;
            for i1=1:lc2
                for i2=1:lc2
                    for i3=1:lc2
                        iatom=iatom+1;
                        rx1(iatom,1)=double(stpos+(i1-1)*sig);
                        rx1(iatom,2)=double(stpos+(i2-1)*sig);
                        rx1(iatom,3)=double(stpos+(i3-1)*sig);
                        de(i3)=double(stpos+(i3-1)*sig);
                    end
                end
            end
            natom=iatom;
            maxer=max(rx1(:,1));
            miner=min(rx1(:,1));
            adder=maxer-miner;
            if mod(lc2,2)==1
                ypos=0;
            else
                ypos=-0.5;
            end
            for i1=1:lc2
                rx2(i1,1)=double(stpos+(i1-1)*sig)+adder;
                rx2(i1,2)=ypos;
                rx2(i1,3)=ypos;
            end
            
            mindist=10;
            uatr=zeros(ndist,1);
            urep=zeros(ndist,1);
            for idist=1:ndist
                for i1=1:natom
                    for i2=1:lc2
                        distsq=(rx2(i2,1)-rx1(i1,1)+ds(idist))^2+(rx2(i2,2)-rx1(i1,2))^2+(rx2(i2,3)-rx1(i1,3))^2;
                        if distsq<mindist
                            mindist=distsq;
                        end
                        dr6=distsq*distsq*distsq;
                        dr12=dr6*dr6;
                        uatr(idist)=uatr(idist)-sig6/dr6;
                        urep(idist)=urep(idist)+sig12/dr12;
                    end
                end
                uatr(idist)=uatr(idist)*4;
                urep(idist)=urep(idist)*4;
            end
            
            sti=1;
            endi=11;
            endi2=find(abs(ds(:)-distcut2)<10^-4);
            x1=ds(sti:endi);
            x1fit=ds(sti:endi+1);
            x2=ds(endi+1:endi2);
            x2fit=ds(endi:endi2);
            yatr1=uatr(sti:endi);
            yatr2=uatr(endi+1:endi2);
            yrep=urep(sti:endi);
            x1log=log10(x1);
            x2log=log10(x2);
            yatr1log=log10(-yatr1);
            yatr2log=log10(-yatr2);
            yreplog=log10(yrep);
            patr1=polyfit(x1log,yatr1log,1);
            patr2=polyfit(x2log,yatr2log,1);
            prep=polyfit(x1log,yreplog,1);
            yatr1fit=-(10^patr1(2))*x1.^patr1(1);
            yatr2fit=-(10^patr2(2))*x2fit.^patr2(1);
            yrepfit=(10^prep(2))*x1.^prep(1);
            
            app.catr1=-10^patr1(2);
            app.atre1=-patr1(1);
            app.catr2=-10^patr2(2);
            app.atre2=-patr2(1);
            app.crep=10^prep(2);
            app.repe=-prep(1);
            
            if (endi2-4)<endi
                app.catr2=app.catr1;
                app.atre2=app.atre1;
            end
        end
        
        %Calculate the positions of the vertices of the cubes
        function [ver1,ver2]=calc_verforplot(app)
            ds_calc=app.ds_calc;
            ds=app.slider_ds.Value;
            lc=app.lc;
            cubelength=app.CubesidelengthnmEditField.Value;
            dyread=app.slider_dy.Value/cubelength;
            dzread=app.slider_dz.Value/cubelength;
            the=(app.slider_theta.Value)/180.0*3.141592;
            phi=(app.slider_phi.Value)/180.0*3.141592;
            psi=(app.slider_psi.Value)/180.0*3.141592;
            amat(1,1)=cos(psi)*cos(phi)-cos(the)*sin(phi)*sin(psi);
            amat(1,2)=cos(psi)*sin(phi)+cos(the)*cos(phi)*sin(psi);
            amat(1,3)=sin(psi)*sin(the);
            	
            amat(2,1)=-sin(psi)*cos(phi)-cos(the)*sin(phi)*cos(psi);
            amat(2,2)=-sin(psi)*sin(phi)+cos(the)*cos(phi)*cos(psi);
            amat(2,3)=cos(psi)*sin(the);
            	
            amat(3,1)=sin(the)*sin(phi);
            amat(3,2)=-sin(the)*cos(phi);
            amat(3,3)=cos(the);
                        
            face1=[-1 1;1 1;1 -1;-1 -1];
            face1=face1*lc; 
            
            ver1(1,:)=[-1 1 1];
            ver1(2,:)=[-1 -1 1];
            ver1(3,:)=[-1 -1 -1];
            ver1(4,:)=[-1 1 -1];
            ver1(5,:)=[1 1 1];
            ver1(6,:)=[1 -1 1];
            ver1(7,:)=[1 -1 -1];
            ver1(8,:)=[1 1 -1];
            ver1=ver1*lc;
            
            for i=1:8
                for j=1:3
                    ver2(i,j)=amat(j,1)*ver1(i,1)+amat(j,2)*ver1(i,2)+amat(j,3)*ver1(i,3);
                end
            end
            [minval,minind]=min(ver2(:,1));
            minver=ver2(minind,:);
            d2(1)=-minver(1)+lc;
            d2(2)=(dyread-0.5)*lc*2-minver(2);
            d2(3)=(dzread-0.5)*lc*2-minver(3);
            for i=1:3
                ver2(:,i)=ver2(:,i)+d2(i);
            end
        end
        
        %Calculate the positions of the vertices of the cubes
        %Calls region_calc(), calc_gen() to compute regions and energies
        %Calls plot_energygen() if flagger==1, exporter() if flagger~=1
        function [ver1,ver2] = calc_ver(app,flagger)
            ds_calc=app.ds_calc;
            ds=app.slider_ds.Value;
            lc=app.lc;
            cubelength=app.CubesidelengthnmEditField.Value;
            dyread=app.slider_dy.Value/cubelength;
            dzread=app.slider_dz.Value/cubelength;
            the=(app.slider_theta.Value)/180.0*3.141592;
            phi=(app.slider_phi.Value)/180.0*3.141592;
            psi=(app.slider_psi.Value)/180.0*3.141592;
            d2=[];            
            uatr=[];
            urep=[];
            utot=[];
            numdist=app.numdist;
            distsave=[];
            for i=1:27
                distsave(i,1)=0.7+(i-1)*0.05;
            end
            dcutter=0.4*lc*2;
            if dcutter>2.5
                dres=(dcutter-2.1)/173;
                for i=28:200
                    distsave(i,1)=2.1+dres*(i-28);
                end
                ndist=200;
            else
                ndist=27;
            end
            facever(1,:)=[1 4 3 2];
            facever(2,:)=[3 4 8 7];
            facever(3,:)=[5 8 7 6];
            facever(4,:)=[1 5 6 2];
            facever(5,:)=[2 3 7 6];
            facever(6,:)=[1 4 8 5];    
            
            fid2d(1,:)=[1 4];
            fid2d(2,:)=[1 6];
            fid2d(3,:)=[4 6];
            fid2d(4,:)=[1 5];
            fid2d(5,:)=[4 5];
            fid2d(6,:)=[1 2];
            fid2d(7,:)=[2 5];
            fid2d(8,:)=[2 6];
            fid2d(9,:)=[3 4];
            fid2d(10,:)=[3 6];
            fid2d(11,:)=[3 5];
            fid2d(12,:)=[2 3];
            
            fid3d(1,:)=[1 4 6];
            fid3d(2,:)=[1 4 5];
            fid3d(3,:)=[1 2 5];
            fid3d(4,:)=[1 2 6];
            fid3d(5,:)=[3 4 6];
            fid3d(6,:)=[3 4 5];
            fid3d(7,:)=[2 3 5];
            fid3d(8,:)=[2 3 6];  
            
            amat(1,1)=cos(psi)*cos(phi)-cos(the)*sin(phi)*sin(psi);
            amat(1,2)=cos(psi)*sin(phi)+cos(the)*cos(phi)*sin(psi);
            amat(1,3)=sin(psi)*sin(the);
            	
            amat(2,1)=-sin(psi)*cos(phi)-cos(the)*sin(phi)*cos(psi);
            amat(2,2)=-sin(psi)*sin(phi)+cos(the)*cos(phi)*cos(psi);
            amat(2,3)=cos(psi)*sin(the);
            	
            amat(3,1)=sin(the)*sin(phi);
            amat(3,2)=-sin(the)*cos(phi);
            amat(3,3)=cos(the);
                        
            face1=[-1 1;1 1;1 -1;-1 -1];
            face1=face1*lc; 
            
            ver1(1,:)=[-1 1 1];
            ver1(2,:)=[-1 -1 1];
            ver1(3,:)=[-1 -1 -1];
            ver1(4,:)=[-1 1 -1];
            ver1(5,:)=[1 1 1];
            ver1(6,:)=[1 -1 1];
            ver1(7,:)=[1 -1 -1];
            ver1(8,:)=[1 1 -1];
            ver1=ver1*lc;
            
            for i=1:8
                for j=1:3
                    ver2(i,j)=amat(j,1)*ver1(i,1)+amat(j,2)*ver1(i,2)+amat(j,3)*ver1(i,3);
                end
            end
            
            [minval,minind]=min(ver2(:,1));
            minver=ver2(minind,:);
            d2(1)=-minver(1)+lc;
            d2(2)=(dyread-0.5)*lc*2-minver(2);
            d2(3)=(dzread-0.5)*lc*2-minver(3);
            
            mincount=0;
            minind=[];
            for i=1:8
                if (ver2(i,1)==minval)
                    mincount=mincount+1;
                    minind(mincount)=i;
                end
            end
            for i=1:3
                ver2(:,i)=ver2(:,i)+d2(i);
            end
            cla(app.plot_face3,'reset');
            hold(app.plot_face3,'on');
            if mincount==4 % Cubes are parallel
                rcount=0;
                for i=1:4
                    tempv(i,:)=ver2(minind(i),:);
                end
                facevs=sortrows(tempv,3);    
                if facevs(3,2)>facevs(4,2)
                    facev(1,:)=facevs(4,:);
                    facev(2,:)=facevs(3,:);
                else
                    facev(1,:)=facevs(3,:);
                    facev(2,:)=facevs(4,:);
                end
                if facevs(1,2)>facevs(2,2)
                    facev(3,:)=facevs(1,:);
                    facev(4,:)=facevs(2,:);
                else
                    facev(3,:)=facevs(2,:);
                    facev(4,:)=facevs(1,:);
                end
                app.plot_face(1,face1,facev);
                region=app.region_calc(facev);
                area=0;
                for i=1:size(region,1)
                    rcount=rcount+1;
                    tempregion(:,:)=region(i,:,:);
                    app.plot_region(1,rcount,tempregion)
    
                    for i3=1:ndist
                        tempface=facev;
                        tempface(:,1)=tempface(:,1)+distsave(i3);
                        [uatrs(i,i3),ureps(i,i3),utots(i,i3),param(i3,:)]=app.calc_gen(tempregion,tempface);
                        if abs(param(i3,7)-distsave(i3,1))>0.1
                            debugger=1;
                        end
                    end
                    tempface=facev;
                    tempface(:,1)=tempface(:,1)+app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                    [ucas(i),ucrs(i),ucts(i),paramc(:)]=app.calc_gen(tempregion,tempface);
                end
                uatr=zeros(ndist,1);
                urep=zeros(ndist,1);
                utot=zeros(ndist,1);
                for i3=1:ndist
                    for i=1:size(region,1)
                        uatr(i3)=uatr(i3)+uatrs(i,i3);
                        urep(i3)=urep(i3)+ureps(i,i3);
                        utot(i3)=utot(i3)+utots(i,i3);
                    end
                end
                uca=0;
                ucr=0;
                uct=0;
                for i=1:size(region,1)    
                    uca=uca+ucas(i);
                    ucr=ucr+ucrs(i);
                    uct=uct+ucts(i);
                end

                tene=[num2str(uct) ' kcal/mol'];
                app.EnergycurrentconfigTextArea.Value=tene;
                if flagger==1
                    dspoint(1)=app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                    dspoint(2)=uct;
                    app.plot_energygen(distsave,uatr,urep,utot,ndist,dspoint);
                else
                    distsave(ndist+1)=app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                    uatr(ndist+1)=uca;
                    urep(ndist+1)=ucr;
                    utot(ndist+1)=uct;
                    app.exporter(distsave,uatr,urep,utot,ndist);
                end
            elseif mincount==2 % An edge of red cube is interacting with blue cube.
                rcount=0;
                if minind(1)==1
                    if minind(2)==2
                        fid=1;
                    elseif minind(2)==4
                        fid=2;
                    elseif minind(2)==5
                        fid=3;
                    end
                elseif minind(1)==2
                    if minind(2)==3
                        fid=4;
                    elseif minind(2)==6
                        fid=5;
                    end
                elseif minind(1)==3
                    if minind(2)==4
                        fid=6;
                    elseif minind(2)==7
                        fid=7;
                    end
                elseif minind(1)==4
                    if minind(2)==8
                        fid=8;
                    end
                elseif minind(1)==5
                    if minind(2)==6
                        fid=9;
                    elseif minind(2)==8
                        fid=10;
                    end
                elseif minind(1)==6
                    if minind(2)==7
                        fid=11;
                    end
                elseif minind(1)==7
                    if minind(2)==8
                        fid=12;
                    end
                end
                
                uatr=zeros(ndist,1);
                urep=zeros(ndist,1);
                utot=zeros(ndist,1);      
                uca=0;
                ucr=0;
                uct=0;                
                for i1=1:2
                    for i2=1:4
                        tempv(i2,:)=ver2(facever(fid2d(fid,i1),i2),:);
                    end
                    facevs=sortrows(tempv,3);    
                    if facevs(3,2)>facevs(4,2)
                        facev(1,:)=facevs(4,:);
                        facev(2,:)=facevs(3,:);
                    else
                        facev(1,:)=facevs(3,:);
                        facev(2,:)=facevs(4,:);
                    end
                    if facevs(1,2)>facevs(2,2)
                        facev(3,:)=facevs(1,:);
                        facev(4,:)=facevs(2,:);
                    else
                        facev(3,:)=facevs(2,:);
                        facev(4,:)=facevs(1,:);
                    end
                    app.plot_face(i1,face1,facev);
                    region=app.region_calc(facev);
                    stopper=1;
                    for i=1:size(region,1)
                        rcount=rcount+1;
                        tempregion(:,:)=region(i,:,:);
                        app.plot_region(i1,rcount,tempregion)

                        for i3=1:ndist
                            tempface=facev;
                            tempface(:,1)=tempface(:,1)+distsave(i3);
                            [uatrs(i,i3),ureps(i,i3),utots(i,i3),param(i3,:)]=app.calc_gen(tempregion,tempface);
                            if abs(param(i3,7)-distsave(i3,1))>0.1
                                debugger=1;
                            end
                        end
                        
                        tempface=facev;
                        tempface(:,1)=tempface(:,1)+app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                        [ucas(i),ucrs(i),ucts(i),paramc(:)]=app.calc_gen(tempregion,tempface);
                    end

                    for i3=1:ndist
                        for i=1:size(region,1)
                            uatr(i3)=uatr(i3)+uatrs(i,i3);
                            urep(i3)=urep(i3)+ureps(i,i3);
                            utot(i3)=utot(i3)+utots(i,i3);
                        end
                    end
                    
                    for i=1:size(region,1)    
                        uca=uca+ucas(i);
                        ucr=ucr+ucrs(i);
                        uct=uct+ucts(i);
                    end
                    stopper=1;
                end
                tene=[num2str(uct) ' kcal/mol'];
                app.EnergycurrentconfigTextArea.Value=tene;
                if flagger==1
                    dspoint(1)=app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                    dspoint(2)=uct;
                    app.plot_energygen(distsave,uatr,urep,utot,ndist,dspoint);
                else
                    distsave(ndist+1)=app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                    uatr(ndist+1)=uca;
                    urep(ndist+1)=ucr;
                    utot(ndist+1)=uct;
                    app.exporter(distsave,uatr,urep,utot,ndist);
                end
            elseif mincount==1 % General configuration where a vertex of red cube is interacting with blue cube.
                rcount=0;
                uatr=zeros(ndist,1);
                urep=zeros(ndist,1);
                utot=zeros(ndist,1);                     
                uca=0;
                ucr=0;
                uct=0;                    
                for i1=1:3
                    for i2=1:4
                        tempv(i2,:)=ver2(facever(fid3d(minind,i1),i2),:);
                    end
                     facevs=sortrows(tempv,3);    
                    if facevs(3,2)>facevs(4,2)
                        facev(1,:)=facevs(4,:);
                        facev(2,:)=facevs(3,:);
                    else
                        facev(1,:)=facevs(3,:);
                        facev(2,:)=facevs(4,:);
                    end
                    if facevs(1,2)>facevs(2,2)
                        facev(3,:)=facevs(1,:);
                        facev(4,:)=facevs(2,:);
                    else
                        facev(3,:)=facevs(2,:);
                        facev(4,:)=facevs(1,:);
                    end
                    app.plot_face(i1,face1,facev);
                    region=app.region_calc(facev);
                    for i=1:size(region,1)
                        rcount=rcount+1;
                        tempregion(:,:)=region(i,:,:);
                        app.plot_region(i1,rcount,tempregion)
                                
                        for i3=1:ndist
                            tempface=facev;
                            tempface(:,1)=tempface(:,1)+distsave(i3);
                            [uatrs(i,i3),ureps(i,i3),utots(i,i3),param(i3,:)]=app.calc_gen(tempregion,tempface);
                            if abs(param(i3,7)-distsave(i3,1))>0.1
                                debugger=1;
                            end
                        end
                        tempface=facev;
                        tempface(:,1)=tempface(:,1)+app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                        [ucas(i),ucrs(i),ucts(i),paramc(:)]=app.calc_gen(tempregion,tempface);
                        stopper=1;
                    end
        
                    for i3=1:ndist
                        for i=1:size(region,1)
                            uatr(i3)=uatr(i3)+uatrs(i,i3);
                            urep(i3)=urep(i3)+ureps(i,i3);
                            utot(i3)=utot(i3)+utots(i,i3);
                        end
                    end

                    for i=1:size(region,1)    
                        uca=uca+ucas(i);
                        ucr=ucr+ucrs(i);
                        uct=uct+ucts(i);
                    end
                    stopper=1;
                        
                end
                tene=[num2str(uct) ' kcal/mol'];
                app.EnergycurrentconfigTextArea.Value=tene;
                if flagger==1
                    dspoint(1)=app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                    dspoint(2)=uct;
                    app.plot_energygen(distsave,uatr,urep,utot,ndist,dspoint);
                else
                    distsave(ndist+1)=app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
                    uatr(ndist+1)=uca;
                    urep(ndist+1)=ucr;
                    utot(ndist+1)=uct;                    
                    app.exporter(distsave,uatr,urep,utot,ndist);
                end
            end
        end
        
        %Export inputted energies to a file
        function exporter(app,distsave,uatr,urep,utot,ndist)
            sigtemp=app.AtomicdiameternmEditField.Value;
            res(:,1)=distsave*sigtemp;
            res(:,2)=app.slider_dy.Value;
            res(:,3)=app.slider_dy.Value;
            res(:,4)=app.slider_phi.Value;
            res(:,5)=app.slider_theta.Value;
            res(:,6)=app.slider_psi.Value;
            res(:,7)=uatr;
            res(:,8)=urep;
            res(:,9)=utot;
            pather=strcat(string(app.ExportpathTextArea.Value),'\');
            filer=string(app.ExportfilenameTextArea.Value);
            fname=strcat(pather,filer);
            col_header={'ds (nm)','dy (nm)','dz (nm)','φ (°)','θ (°)','ψ (°)','uatt (kcal/mol)','urep (kcal/mol)','utot (kcal/mol)'};
            col_header={'ds','dy','dz','phi','theta','psi','uatt','urep','utot'};
            tabler=array2table(res,'VariableNames',col_header);
            writetable(tabler,fname);
        end
        
        %Plots yz projection of the inputted region
        function plot_region(app,id,cid,regionin)
           if mod(cid,5)==1
               facecolor=[128/255 0 128/255];
           elseif mod(cid,5)==2
               facecolor=[0 128/255 0];
           elseif mod(cid,5)==3
               facecolor=[128/255 0 0];
           elseif mod(cid,5)==4
               facecolor=[0 0 128/255];
           elseif mod(cid,5)==0
               facecolor=[128/255 128/255 0];
           end
           cubelength=app.CubesidelengthnmEditField.Value;
           region=regionin/app.lc*cubelength;           
           patch(app.plot_face3,'Vertices',region,'Faces',[1 2 4 3],'FaceColor',facecolor,'EdgeColor','None','FaceAlpha',0.4)
           set(app.plot_face3,'xtick',[-cubelength cubelength])
           set(app.plot_face3,'ytick',[-cubelength cubelength])
            set(app.plot_face3,'fontsize',14)
            app.plot_face3.Title.FontSize=16;
            app.plot_face3.XLabel.FontSize=16;
            app.plot_face3.YLabel.FontSize=16;
        end
        
        %Plots yz projection of the inputted faces
        function plot_face(app,id,face1in,face2in)
            Lwidth=1.5;
            cubelength=app.CubesidelengthnmEditField.Value;
            face1=face1in/app.lc*cubelength;
            face2=face2in/app.lc*cubelength;
            patch(app.plot_face3,'Vertices',face1,'Faces',[1 2 3 4],'FaceColor','None','EdgeColor','blue','LineWidth',Lwidth)
            patch(app.plot_face3,'Vertices',face2(:,2:3),'Faces',[1 2 3 4],'FaceColor','None','EdgeColor','red','LineWidth',Lwidth)
            app.plot_face3.XLim=[-cubelength*1.5 cubelength*1.5];
            app.plot_face3.YLim=[-cubelength*1.5 cubelength*1.5];
            app.plot_face3.Title.String='Interacting regions';
            app.plot_face3.XLabel.String='{\it y} (nm)';
            app.plot_face3.YLabel.String='{\it z} (nm)';
            app.plot_face3.Title.FontSize=16;
            app.plot_face3.XLabel.FontSize=16;
            app.plot_face3.YLabel.FontSize=16;         
        end
        
        %Plots 3D depiction of the two cubes with the configuration from user input.
        function plot_cube(app,flagcube)
            if flagcube==1
                [ver1,ver2]=app.calc_ver(1);
            else
                [ver1,ver2]=app.calc_verforplot();
                a=1;
            end
            sigtemp=app.AtomicdiameternmEditField.Value;
            ver2(:,1)=ver2(:,1)-app.ds_calc(1)+app.slider_ds.Value/sigtemp;
            lc=app.lc;
            fac=[1 2 3 4;3 4 8 7;5 8 7 6;1 5 6 2;2 3 7 6;1 4 8 5];            
            cla(app.plot_cubevis,'reset');
            xorigin=[lc*4;lc*-2.5;0];

            patch(app.plot_cubevis,'Vertices',ver1,'Faces',fac,'FaceColor','blue')
            patch(app.plot_cubevis,'Vertices',ver2,'Faces',fac,'FaceColor','red')
            
            app.plot_cubevis.XLim=[-lc*1.2 lc*4.8];
            app.plot_cubevis.YLim=[-lc*3 lc*3];
            app.plot_cubevis.ZLim=[-lc*3 lc*3];
        
            set(app.plot_cubevis,'visible','off')
            rotate3d(app.plot_cubevis,'on')
        end
        
        %Segment the inputted face into regions with constant y integral
        %limits and z integral limits that are linear functions of y.
        function region = region_calc(app,facein)
            lc=app.lc;
            reg_tol=lc+1e-9;
            temper=sortrows(facein,2,'ascend');
            face2(1,:)=temper(1,:);
            if temper(2,3)>temper(3,3)
                face2(2,:)=temper(2,:);
                face2(3,:)=temper(3,:);
            else
                face2(2,:)=temper(3,:);
                face2(3,:)=temper(2,:);
            end
            face2(4,:)=temper(4,:);
            xcount=0;
            xsave=[];

            %Line1
            L1_slope=(face2(2,2)-face2(1,2))/(face2(2,3)-face2(1,3));
            L1y1=(-lc-face2(2,3))*L1_slope+face2(2,2);
            L1y2=(lc-face2(2,3))*L1_slope+face2(2,2);
            if L1y1>face2(1,2) & L1y1<face2(2,2) & L1y1<=lc & L1y1>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L1y1;
            end
            if L1y2>face2(1,2) & L1y2<face2(2,2) & L1y2<=lc & L1y2>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L1y2;              
            end
            
            %Line2
            L2_slope=(face2(4,2)-face2(2,2))/(face2(4,3)-face2(2,3));
            L2y1=(-lc-face2(2,3))*L2_slope+face2(2,2);
            L2y2=(lc-face2(2,3))*L2_slope+face2(2,2);
            if L2y1<face2(4,2) & L2y1>face2(2,2) & L2y1<=lc & L2y1>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L2y1;               
            end
            if L2y2<face2(4,2) & L2y2>face2(2,2) & L2y2<=lc & L2y2>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L2y2;               
            end
            
            %Line3
            L3_slope=(face2(3,2)-face2(1,2))/(face2(3,3)-face2(1,3));
            L3y1=(-lc-face2(3,3))*L3_slope+face2(3,2);
            L3y2=(lc-face2(3,3))*L3_slope+face2(3,2);
            if L3y1>face2(1,2) & L3y1<face2(3,2) & L3y1<=lc & L3y1>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L3y1;              
            end
            if L3y2>face2(1,2) & L3y2<face2(3,2) & L3y2<=lc & L3y2>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L3y2;              
            end     
            
            %Line4
            L4_slope=(face2(4,2)-face2(3,2))/(face2(4,3)-face2(3,3));
            L4y1=(-lc-face2(3,3))*L4_slope+face2(3,2);
            L4y2=(lc-face2(3,3))*L4_slope+face2(3,2);
            if L4y1<face2(4,2) & L4y1>face2(3,2) & L4y1<=lc & L4y1>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L4y1;               
            end
            if L4y2<face2(4,2) & L4y2>face2(3,2) & L4y2<=lc & L4y2>=-lc
                xcount=xcount+1;
                xsave(xcount,1)=L4y2;                
            end 
            
            for i=1:4
                if face2(i,2)>=-lc & face2(i,2)<=lc & face2(i,3)>=-lc & face2(i,3)<=lc
                   xcount=xcount+1;
                   xsave(xcount,1)=face2(i,2);
                end
            end
            
            if face2(1,2)<-lc
                xcount=xcount+1;
                xsave(xcount,1)=-lc;
            end
            if face2(4,2)>lc
                xcount=xcount+1;
                xsave(xcount,1)=lc;
            end
            xsort1=sortrows(xsave,1);
            xsort=unique(xsort1);
            %xsort contains all of the possible y positions where region
            %might need to be separated.
            
            %Define region
            region=[];
            regioncount=0;
            for i1=2:size(xsort,1)
                RYLB=xsort(i1-1,1);
                RYUB=xsort(i1,1);
                if RYLB<face2(2,2)
                    slope=(face2(2,3)-face2(1,3))/(face2(2,2)-face2(1,2));
                    if isinf(slope)
                        RZUB1=face2(2,3);
                    else
                        RZUB1=(slope)*(RYLB-face2(2,2))+face2(2,3);
                    end
                else
                    slope=(face2(4,3)-face2(2,3))/(face2(4,2)-face2(2,2));
                    if isinf(slope)
                        RZUB1=face2(2,3);
                    else
                        RZUB1=(slope)*(RYLB-face2(2,2))+face2(2,3);                        
                    end
                end
                if RYUB<face2(2,2)
                    slope=(face2(2,3)-face2(1,3))/(face2(2,2)-face2(1,2));
                    if isinf(slope)
                        RZUB2=face2(2,3);
                    else
                        RZUB2=(slope)*(RYUB-face2(2,2))+face2(2,3);                        
                    end
                else
                    slope=(face2(4,3)-face2(2,3))/(face2(4,2)-face2(2,2));
                    if isinf(slope)
                        RZUB2=face2(2,3);
                    else
                        RZUB2=(slope)*(RYUB-face2(2,2))+face2(2,3);                        
                    end                    
                end
                
                if RYLB<face2(3,2)
                    slope=(face2(3,3)-face2(1,3))/(face2(3,2)-face2(1,2));
                    if isinf(slope)
                        RZLB1=face2(3,3);
                    else
                        RZLB1=(slope)*(RYLB-face2(3,2))+face2(3,3);
                    end
                else
                    slope=(face2(4,3)-face2(3,3))/(face2(4,2)-face2(3,2));
                    if isinf(slope)
                        RZLB1=face2(3,3);
                    else
                        RZLB1=(slope)*(RYLB-face2(3,2))+face2(3,3);                        
                    end
                end
                if RYUB<face2(3,2)
                    slope=(face2(3,3)-face2(1,3))/(face2(3,2)-face2(1,2));
                    if isinf(slope)
                        RZLB2=face2(3,3);
                    else
                        RZLB2=(slope)*(RYUB-face2(3,2))+face2(3,3);                        
                    end
                else
                    slope=(face2(4,3)-face2(3,3))/(face2(4,2)-face2(3,2));
                    if isinf(slope)
                        RZLB2=face2(3,3);
                    else
                        RZLB2=(slope)*(RYUB-face2(3,2))+face2(3,3);                        
                    end
                end
                if RZUB1>lc
                    RZUB1=lc;
                end
                if RZUB2>lc
                    RZUB2=lc;
                end
                if RZLB1<-lc
                    RZLB1=-lc;
                end
                if RZLB2<-lc
                    RZLB2=-lc;
                end
                
                if RZUB1>=-reg_tol & RZUB2>=-reg_tol & RZLB1<=reg_tol & RZLB2<=reg_tol
                    regioncount=regioncount+1;
                    region(regioncount,1,1)=RYLB;
                    region(regioncount,1,2)=RZUB1;
                    region(regioncount,2,1)=RYUB;
                    region(regioncount,2,2)=RZUB2;
                    region(regioncount,3,1)=RYLB;
                    region(regioncount,3,2)=RZLB1;
                    region(regioncount,4,1)=RYUB;
                    region(regioncount,4,2)=RZLB2;                    
                end
            end
        end
        
        %Compute the interaction energies from atomistic calculations by calling calc_atom()
        %If flagger==1, plot the energies on the application as scatter points.
        %IF flagger==2, export the energies to a file by calling exporter().
        function plot_atomenergy(app,flagger)
            ds=app.slider_ds.Value;
            lc=app.lc;
            
            ds_calc=[1.0;1.5;2.0];
            dcutter=0.4*lc*2;
            checker=0;
            dsconf=app.slider_ds.Value/app.AtomicdiameternmEditField.Value;
            if dcutter>2.5
                dres=(dcutter-2.5)/4;
                for i=4:7
                    ds_calc(i,1)=2.5+dres*(i-4);
                end
                checker=1;
                ds_calc(8,1)=dsconf;
            else
                ds_calc(4,1)=dsconf;
            end
            sigtemp=app.AtomicdiameternmEditField.Value;
            cubelength=app.CubesidelengthnmEditField.Value;
            dyread=app.slider_dy.Value/cubelength;
            dzread=app.slider_dz.Value/cubelength;
            the=(app.slider_theta.Value)/180.0*3.141592;
            phi=(app.slider_phi.Value)/180.0*3.141592;
            psi=(app.slider_psi.Value)/180.0*3.141592;
            amat(1,1)=cos(psi)*cos(phi)-cos(the)*sin(phi)*sin(psi);
            amat(1,2)=cos(psi)*sin(phi)+cos(the)*cos(phi)*sin(psi);
            amat(1,3)=sin(psi)*sin(the);
            	
            amat(2,1)=-sin(psi)*cos(phi)-cos(the)*sin(phi)*cos(psi);
            amat(2,2)=-sin(psi)*sin(phi)+cos(the)*cos(phi)*cos(psi);
            amat(2,3)=cos(psi)*sin(the);
            	
            amat(3,1)=sin(the)*sin(phi);
            amat(3,2)=-sin(the)*cos(phi);
            amat(3,3)=cos(the);


            face1=[-1 1;1 1;1 -1;-1 -1];
            face1=face1*lc; 
            
            ver1(1,:)=[-1 1 1];
            ver1(2,:)=[-1 -1 1];
            ver1(3,:)=[-1 -1 -1];
            ver1(4,:)=[-1 1 -1];
            ver1(5,:)=[1 1 1];
            ver1(6,:)=[1 -1 1];
            ver1(7,:)=[1 -1 -1];
            ver1(8,:)=[1 1 -1];
            ver1=ver1*lc;
            
            for i=1:8
                for j=1:3
                    ver2(i,j)=amat(j,1)*ver1(i,1)+amat(j,2)*ver1(i,2)+amat(j,3)*ver1(i,3);
                end
            end
            
            [minval,minind]=min(ver2(:,1));
            minver=ver2(minind,:);
            d2(2)=(dyread-0.5)*lc*2-minver(2);
            d2(3)=(dzread-0.5)*lc*2-minver(3);
            ndist=size(ds_calc,1);
            incre=floor(1/ndist*100);
            for i1=1:ndist
                tstr=['Computing atomistic summation: ' int2str((i1-1)*incre) '% completed'];
                drawnow
                app.StatusTextArea.Value=tstr;
                d2(1)=ds_calc(i1)-minver(1)+lc-app.sig;
                [uatr(i1,1),urep(i1,1),utot(i1,1)]=app.calc_atom(d2,amat);
            end       
            tstr=['Computing atomistic summation: 100% completed'];
            drawnow
            app.StatusTextArea.Value=tstr;
            stopper=1;
            if flagger==1
                if isprop(app.plot_aa1,'Visible')
                    app.plot_aa1.Visible='off';
                end
                if isprop(app.plot_aa2,'Visible')
                    app.plot_aa2.Visible='off';
                end
                if isprop(app.plot_aa3,'Visible')
                    app.plot_aa3.Visible='off';
                end
                distcut1=3;
                distcut2=7;
                app.plot_aa1=plot(app.plot_energy,ds_calc(1:distcut1)*sigtemp,utot(1:distcut1),'ko','MarkerFaceColor','k');           
                app.plot_aa2=plot(app.plot_energy,ds_calc(1:distcut1)*sigtemp,uatr(1:distcut1),'ro','MarkerFaceColor','r');
                app.plot_aa3=plot(app.plot_energy,ds_calc(1:distcut1)*sigtemp,urep(1:distcut1),'bo','MarkerFaceColor','b');     
                legend(app.plot_energy,'U_{tot,model}','U_{att,model}','U_{rep,model}','U_{tot,atom}','U_{att,atom}','U_{rep,atom}','FontSize',12,'Location','best')
               
                if checker==1
                    app.plot_aa1=plot(app.plot_face1,ds_calc(distcut1:distcut2)*sigtemp,utot(distcut1:distcut2),'ko','MarkerFaceColor','k');           
                    legend(app.plot_face1,'U_{tot,model}','U_{tot,atom}','FontSize',12,'Location','best')
                end
                sizer=60;
                if dsconf<2
                    app.plot_dat=scatter(app.plot_energy,ds_calc(ndist)*sigtemp,utot(ndist),sizer,'k','v','filled','HandleVisibility','off');
                else
                    app.plot_dat=scatter(app.plot_face1,ds_calc(ndist)*sigtemp,utot(ndist),sizer,'k','v','filled','HandleVisibility','off');
                end
                tene=[num2str(utot(ndist)) ' kcal/mol'];
                app.EnergycurrentconfigTextArea.Value=tene;
            else
               app.exporter(ds_calc,uatr,urep,utot,7);
            end
        end
        
        %Compute interaction energies through atomistic calculations.
        function [uatr,urep,utot] = calc_atom(app,d2,amat)
            sig=app.sig;
            iatom=0;
            stpos=-app.lc+sig/2.0;
            sig6=sig^6;
            sig12=sig^12;
            uatr=0;
            urep=0;
            utot=0;
            lc2=floor(app.lc*2/sig);
            natom=lc2^3;
            for i1=1:lc2
                for i2=1:lc2
                    for i3=1:lc2
                        iatom=iatom+1;
                        rx1(iatom,1)=double(stpos+(i1-1)*sig);
                        rx1(iatom,2)=double(stpos+(i2-1)*sig);
                        rx1(iatom,3)=double(stpos+(i3-1)*sig);
                        de(i3)=double(stpos+(i3-1)*sig);
                    end
                end
            end
            
            for i1=1:natom
                for i2=1:3
                    rx2(i1,i2)=amat(i2,1)*rx1(i1,1)+amat(i2,2)*rx1(i1,2)+amat(i2,3)*rx1(i1,3);
                end
            end            
            rx2(:,1)=rx2(:,1)+d2(1);
            rx2(:,2)=rx2(:,2)+d2(2);
            rx2(:,3)=rx2(:,3)+d2(3);            
            mindist=10;
            for i1=1:natom
                for i2=1:natom
                    distsq=(rx2(i2,1)-rx1(i1,1))^2+(rx2(i2,2)-rx1(i1,2))^2+(rx2(i2,3)-rx1(i1,3))^2;
                    if distsq<mindist
                        mindist=distsq;
                    end
                    dr6=distsq*distsq*distsq;
                    dr12=dr6*dr6;
                    uatr=uatr-sig6/dr6;
                    urep=urep+sig12/dr12;
                end
            end
            uatr=uatr*4.0*app.eps;
            urep=urep*4.0*app.eps;
            utot=uatr+urep;
        end
        
        %Computes the analytical expressions for the energies when dx=dx(z)
        function energy= ecalcc1(app,param,cene,n,c3now,caseind)
            t=num2cell(param);
            [a1,a2,b1,b2,c1,c2,c3,ym,yp,sig]=deal(t{:});
            if caseind==1
                energy=cene*sig^(n-2)/c2/(1-n)*((yp-ym)*(a2*c2+c3now)^(1-n)+(ym-yp)*(a1*c2+c3now)^(1-n));
            elseif caseind==2
                u1=(yp-ym)*(a2*c2+c3now)^(1-n);
                u2=(a1*c2+c3now+b1*c2*ym)^(2-n)-(a1*c2+c3now+b1*c2*yp)^(2-n);
                u2=u2/(b1*c2*(2-n));
                energy=cene*sig^(n-2)/c2/(1-n)*(u1+u2);    
            elseif caseind==3
                u1=(ym-yp)*(a1*c2+c3now)^(1-n);
                u2=(a2*c2+c3now+b2*c2*yp)^(2-n)-(a2*c2+c3now+b2*c2*ym)^(2-n);
                u2=u2/(b2*c2*(2-n));
                energy=cene*sig^(n-2)/c2/(1-n)*(u1+u2);
            elseif caseind==4
                u1=-(a2*c2+c3now+b2*c2*ym)^(2-n)+(a2*c2+c3now+b2*c2*yp)^(2-n);
                u1=u1/(2*b2*c2-b2*c2*n);
                u2=(a1*c2+c3now+b1*c2*ym)^(2-n)-(a1*c2+c3now+b1*c2*yp)^(2-n);
                u2=u2/(2*b1*c2-b1*c2*n);
                energy=cene*(u1+u2)*sig^(n-2)/(c2*(1-n));
            end
        end
        
        %Computes the analytical expressions for the energies when dx=dx(y)
        function energy= ecalcc2(app,param,cene,n,c3now,caseind)
            t=num2cell(param);
            [a1,a2,b1,b2,c1,c2,c3,ym,yp,sig]=deal(t{:});
            
            u1=(c1*yp+c3now)^(1-n)*((a1*c1-a2*c1)*(n-2)+(b1-b2)*(c3now+c1*(n-1)*yp));
            u2=-(c1*ym+c3now)^(1-n)*((a1*c1-a2*c1)*(n-2)+(b1-b2)*(c3now+c1*(n-1)*ym));
            energy=cene*sig^(n-2)/((c1)^2)/(n-2)/(n-1)*(u1+u2);
        end
        
        %Computes the analytical expressions for the energies when dx=dx(y,z)
        function energy=ecalcgen(app,param,cene,n,c3now,caseind)
            t=num2cell(param);
            [a1,a2,b1,b2,c1,c2,c3,ym,yp,sig]=deal(t{:});
            
            if caseind==1 %c1+b1c2=c1+b2c2=0
                u1=(yp-ym)*(a1*c2+c3now)^(1-n);
                u2=(ym-yp)*(a2*c2+c3now)^(1-n);
                energy=cene*(u1+u2)*sig^(n-2)/(c2*(n-1)*(n-2));
            elseif caseind==2 %c1+b1c2=0
                u1=(yp-ym)*(a1*c2+c3now)^(1-n);
                u2=-(a2*c2+c3now+(c1+b2*c2)*ym)^(2-n)+(a2*c2+c3now+(c1+b2*c2)*yp)^(2-n);
                u2=u2/((c1+b2*c2));
                energy=cene*(u1+u2)*sig^(n-2)/(c2*(n-1)*(n-2)); 
            elseif caseind==3 %c1+b2c2=0
                u1=(a1*c2+c3now+(c1+b1*c2)*ym)^(2-n)-(a1*c2+c3now+(c1+b1*c2)*yp)^(2-n);
                u1=u1/((c1+b1*c2));
                u2=(ym-yp)*(a2*c2+c3now)^(1-n);
                energy=cene*(u1+u2)*sig^(n-2)/(c2*(n-1)*(n-2));
            elseif caseind==4
                u1=(a1*c2+c3now+(c1+b1*c2)*ym)^(2-n)-(a1*c2+c3now+(c1+b1*c2)*yp)^(2-n);
                u1=u1/((c1+b1*c2));
                u2=-(a2*c2+c3now+(c1+b2*c2)*ym)^(2-n)+(a2*c2+c3now+(c1+b2*c2)*yp)^(2-n);
                u2=u2/((c1+b2*c2));
                energy=cene*(u1+u2)*sig^(n-2)/(c2*(n-1)*(n-2));
            end
        end
        
        %Compute energies through the analytical model.
        function [uatr,urep,utot,param] = calc_gen(app,region,facein)
            sig=app.sig;
            lc=app.lc;
            atre1=app.atre1;
            atre2=app.atre2;
            repe=app.repe;
            catr1=app.catr1;
            catr2=app.catr2;
            crep=app.crep;
            dslim=2.0;
            slopetol=10^-5;
            temper=sortrows(facein,2,'ascend');
            face(1,:)=temper(1,:);
            face(4,:)=temper(4,:);
            if (temper(2,3)>temper(3,3))
                face(2,:)=temper(2,:);
                face(3,:)=temper(3,:);
            else
                face(2,:)=temper(3,:);
                face(3,:)=temper(2,:);
            end
            yp=region(2,1);
            ym=region(1,1);
            b2=(region(2,2)-region(1,2))/(region(2,1)-region(1,1));
            a2=region(1,2)-b2*region(1,1);
            b1=(region(4,2)-region(3,2))/(region(4,1)-region(3,1));
            a1=region(3,2)-b1*region(3,1);
            
            AA=(face(2,2)-face(1,2))*(face(3,3)-face(1,3))-(face(2,3)-face(1,3))*(face(3,2)-face(1,2));
            BB=(face(2,3)-face(1,3))*(face(3,1)-face(1,1))-(face(2,1)-face(1,1))*(face(3,3)-face(1,3));
            CC=((face(2,1)-face(1,1))*(face(3,2)-face(1,2))-(face(2,2)-face(1,2))*(face(3,1)-face(1,1)));
            
            c1=-BB/AA;
            c2=-CC/AA;
            c3=face(1,1)-lc-c1*face(1,2)-c2*face(1,3);     
            debug=0;
            param=[a1,a2,b1,b2,c1,c2,c3,ym,yp,sig];
             if c3>dslim
              if abs(c2)<slopetol && abs(c1)<slopetol
                    area=((region(1,2)-region(3,2))+(region(2,2)-region(4,2)))*(region(2,1)-region(1,1))/2;
                    n=atre2;
                    uatr=catr2*area*c3^(-n);
                    urep=0.0;    
                elseif abs(c1)<slopetol
                    if abs(b1)<slopetol && abs(b2)<slopetol
                        caseindc1=1;
                    elseif abs(b2)<slopetol
                        caseindc1=2;
                    elseif abs(b1)<slopetol
                        caseindc1=3;
                    else
                        caseindc1=4;
                    end                     
                    uatr=ecalcc1(app,param,catr2,atre2,c3,caseindc1);
                    urep=0.0;                
                elseif abs(c2)<slopetol
                    n=atre2;
                    uatr=ecalcc2(app,param,catr2,atre2,c3,1);
                    urep=0.0;
              else
                    check1=abs(c1+b1*c2);
                    check2=abs(c1+b2*c2);
                    if check1<slopetol && check2<slopetol
                        caseindgen=1;
                    elseif check1<slopetol
                        caseindgen=2;
                    elseif check2<slopetol
                        caseindgen=3;
                    else
                        caseindgen=4;
                    end                     
                    uatr=ecalcgen(app,param,catr2,atre2,c3,caseindgen);
                    urep=0.0; 
                end
            else
                c3t=dslim;      
                if abs(c2)<slopetol && abs(c1)<slopetol
                    area=((region(1,2)-region(3,2))+(region(2,2)-region(4,2)))*(region(2,1)-region(1,1))/2;
                    n=atre1;
                    uatr=catr1*area*c3^(-n);
                    nt2=atre2;
                    nt1=atre1;
                    uatrt2=catr2*area*c3t^(-nt2);
                    uatrt1=catr1*area*c3t^(-nt1);
                    uatr=uatr+uatrt2-uatrt1;
                    n=repe;
                    urep=crep*area*c3^(-n);    
                    ureptemp=crep*area*c3t^(-n);
                    urep=urep-ureptemp;
                elseif abs(c1)<slopetol
                    if abs(b1)<slopetol && abs(b2)<slopetol
                        caseindc1=1;
                    elseif abs(b2)<slopetol
                        caseindc1=2;
                    elseif abs(b1)<slopetol
                        caseindc1=3;
                    else
                        caseindc1=4;
                    end                
                    uatr=ecalcc1(app,param,catr1,atre1,c3,caseindc1);
                    uatrt1=ecalcc1(app,param,catr1,atre1,c3t,caseindc1);
                    uatrt2=ecalcc1(app,param,catr2,atre2,c3t,caseindc1);
                    uatr=uatr+uatrt2-uatrt1;
                    urep=ecalcc1(app,param,crep,repe,c3,caseindc1);
                    ureptemp=ecalcc1(app,param,crep,repe,c3t,caseindc1);
                    urep=urep-ureptemp;
                elseif abs(c2)<slopetol
                    uatr=ecalcc2(app,param,catr1,atre1,c3,1);
                    uatrt1=ecalcc2(app,param,catr1,atre1,c3t,1);
                    uatrt2=ecalcc2(app,param,catr2,atre2,c3t,1);
                    uatr=uatr+uatrt2-uatrt1;
                    urep=ecalcc2(app,param,crep,repe,c3,1);
                    ureptemp=ecalcc2(app,param,crep,repe,c3t,1);
                    urep=urep-ureptemp;             
                else
                    check1=abs(c1+b1*c2);
                    check2=abs(c1+b2*c2);
                    if check1<slopetol && check2<slopetol
                        caseindgen=1;
                    elseif check1<slopetol
                        caseindgen=2;
                    elseif check2<slopetol
                        caseindgen=3;
                    else
                        caseindgen=4;
                    end                       
                    uatr=ecalcgen(app,param,catr1,atre1,c3,caseindgen);
                    uatrt1=ecalcgen(app,param,catr1,atre1,c3t,caseindgen);
                    uatrt2=ecalcgen(app,param,catr2,atre2,c3t,caseindgen);
                    uatr=uatr+uatrt2-uatrt1;
                    urep=ecalcgen(app,param,crep,repe,c3,caseindgen);
                    ureptemp=ecalcgen(app,param,crep,repe,c3t,caseindgen);
                    urep=urep-ureptemp;                    
                end
             end
        uatr=uatr*app.eps;
        urep=urep*app.eps;
        utot=uatr+urep;
        end     
        
        %Plots energy computed with the analytical model as solid lines.
        function plot_energygen(app,dist,uatr,urep,utot,ndist,dspoint)
            cla(app.plot_energy,'reset')
            cla(app.plot_face1,'reset')
            hold(app.plot_energy,'on');
            hold(app.plot_face1,'on');
            distcut1=find(dist==2.0);
            distcut2=ndist;
            Lwidth=1.5;
            sigtemp=app.AtomicdiameternmEditField.Value;
            dsnow=app.slider_ds.Value/sigtemp;
            [ddd, ix] = min(abs(dist-dsnow));
            app.plot_gen1=plot(app.plot_energy,dist(1:distcut1)*sigtemp,utot(1:distcut1),'-k','LineWidth',Lwidth);           
            app.plot_gen2=plot(app.plot_energy,dist(1:distcut1)*sigtemp,uatr(1:distcut1),'-r','LineWidth',Lwidth);
            app.plot_gen3=plot(app.plot_energy,dist(1:distcut1)*sigtemp,urep(1:distcut1),'-b','LineWidth',Lwidth);  

            dcutgen=app.distcutgen;
            ywindowmin=min(utot(1:distcut1));
            if ywindowmin<0
                ywindowmin=ywindowmin*1.5;
            end
            ywindowmax=max(urep);
            maxval=50;
            if ywindowmax>maxval
                ywindowmax=maxval;
            end
            if ywindowmax==ywindowmin
                ywindowmax=ywindowmin+maxval;
            elseif isnan(ywindowmin) | isnan(ywindowmax)
                ywindowmin=-maxval;
                ywindowmax=maxval;
            end
            app.plot_energy.YLim=[ywindowmin ywindowmax];    
            app.plot_energy.XLim=[0.9*sigtemp 2.0*sigtemp];
            app.plot_energy.Title.String='Short-range interaction';
            app.plot_energy.XLabel.String='{\it d}_s (nm)';
            app.plot_energy.YLabel.String='{\it U} (kcal/mol)';
            set(app.plot_energy,'fontsize',14)
            app.plot_energy.Title.FontSize=16;
            app.plot_energy.XLabel.FontSize=16;
            app.plot_energy.YLabel.FontSize=16;
            legend(app.plot_energy,'{\it U}_{tot}','{\it U}_{att}','{\it U}_{rep}','FontSize',16,'Location','best')
            if (distcut2>distcut1) 
                app.plot_gen1=plot(app.plot_face1,dist(distcut1:distcut2)*sigtemp,utot(distcut1:distcut2),'-k','LineWidth',Lwidth);           
                ywindowmin=min(utot(distcut1:distcut2));
                if ywindowmin<0
                    ywindowmin=ywindowmin*1.1;
                else
                    ywindowmin=-10;
                end
                ywindowmax=max(utot(distcut1:distcut2));
                if ywindowmax>100
                    ywindowmax=100;
                end
                if ywindowmax==ywindowmin
                    ywindowmax=ywindowmin+100;
                elseif isnan(ywindowmin) OR isnan(ywindowmax)
                    ywindowmin=-100;
                    ywindowmax=100;
                end
                ywindowmax=0;
                app.plot_face1.YLim=[ywindowmin ywindowmax];    
                app.plot_face1.XLim=[2.0*sigtemp dist(distcut2)*sigtemp];
                app.plot_face1.Title.String='Long-range interaction';
                app.plot_face1.XLabel.String='{\it d}_s (nm)';
                app.plot_face1.YLabel.String='{\it U} (kcal/mol)';
                set(app.plot_face1,'fontsize',14)
                app.plot_face1.Title.FontSize=16;
                app.plot_face1.XLabel.FontSize=16;
                app.plot_face1.YLabel.FontSize=16;
                legend(app.plot_face1,'{\it U}_{tot}','FontSize',16,'Location','best')
            end
            sizer=60;
            if dspoint(1)<2
                app.plot_dat=scatter(app.plot_energy,dspoint(1)*sigtemp,dspoint(2),sizer,'k','HandleVisibility','off');
            else
                app.plot_dat=scatter(app.plot_face1,dspoint(1)*sigtemp,dspoint(2),sizer,'k','HandleVisibility','off');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dsLabel.Text='ds(nm)';
            app.dyLabel.Text='dy(nm)';
            app.dzLabel.Text='dz(nm)';
            app.phLabel.Text=[char(966) '(°)'];
            app.thLabel.Text=[char(952) '(°)'];            
            app.psLabel.Text=[char(968) '(°)'];   
            app.Densitygcm3EditFieldLabel.Text=['Density (g/cm' char(179) ')'];
            app.slider_ds.MajorTickLabels = {'1', '2', '4', '6','8', '10'};
            app.slider_ds.MajorTicks = [1.0 2.0 4.0 6.0 8.0 10.0];
            app.MaterialDropDownValueChanged();
            app.EnterparametersButtonPushed;
            app.plot_cube(1);            
            hold(app.plot_face3,'on');
            app.ExportpathTextArea.Value=pwd;
        end

        % Button pushed function: ComputeanalyticalButton
        function ComputeanalyticalButtonPushed(app, event)
            app.StatusTextArea.Value='Computing analytic energies';        
            app.plot_cube(1);
            app.StatusTextArea.Value='Idle';
        end

        % Button pushed function: ComputeatomisticButton
        function ComputeatomisticButtonPushed(app, event)
            app.StatusTextArea.Value='Computing atomistic summation';
            app.plot_atomenergy(1);               
            app.StatusTextArea.Value='Idle';
        end

        % Value changed function: EditField_phi
        function EditField_phiValueChanged(app, event)
            value = app.EditField_phi.Value;
            app.slider_phi.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: EditField_theta
        function EditField_thetaValueChanged(app, event)
            value = app.EditField_theta.Value;
            app.slider_theta.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: EditField_psi
        function EditField_psiValueChanged(app, event)
            value = app.EditField_psi.Value;
            app.slider_psi.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: EditField_dy
        function EditField_dyValueChanged(app, event)
            value = app.EditField_dy.Value;
            app.slider_dy.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: EditField_dz
        function EditField_dzValueChanged(app, event)
            value = app.EditField_dz.Value;
            app.slider_dz.Value=value;
            app.plot_cube(2);
        end

        % Button pushed function: EnterparametersButton
        function EnterparametersButtonPushed(app, event)
            Ham=app.Hamakerconstant10E19JEditField.Value;
            dia=(2^(1/6))*app.AtomicdiameternmEditField.Value;
            mden=app.Densitygcm3EditField.Value;
            aweight=app.AtomicweightDaEditField.Value;
            navo=6.02214*10^23;
            den=mden/aweight*navo*10^-21;
            epscalc=Ham*10^-19/dia^6/4/pi^2/den^2/6.9477/10^-21;
            if app.LJenergyparameterEditField.Value==0
                app.eps=epscalc;
            else
                app.eps=app.LJenergyparameterEditField.Value;
            end
            sigtemp=app.AtomicdiameternmEditField.Value;
            cubelength=app.CubesidelengthnmEditField.Value;
            app.lc=floor(cubelength/sigtemp)/2;
            app.sig=1.0;
            value=app.CODE_NAME.Value;
            dslim=cubelength*0.4;
            app.slider_ds.Limits=[sigtemp dslim];
            ticker=[0.1*cubelength 0.2*cubelength 0.3*cubelength 0.4*cubelength];
            ticker2={num2str(0.1*cubelength), num2str(0.2*cubelength), num2str(0.3*cubelength), num2str(0.4*cubelength)};
            app.slider_ds.MajorTicks=ticker;
            app.slider_ds.MajorTickLabels=ticker2;
            app.slider_dy.Limits=[0 cubelength];
            app.slider_dz.Limits=[0 cubelength];
            app.slider_ds.Value=sigtemp;
            app.EditField_ds.Value=sigtemp;
            app.slider_dy.Value=cubelength*0.5;
            app.EditField_dy.Value=cubelength*0.5;
            app.slider_dz.Value=cubelength*0.5;
            app.EditField_dz.Value=cubelength*0.5;
            
            if strcmp(value,'Default')
                app.paramdefault();
            elseif strcmp(value,'Recalibrate')
                app.StatusTextArea.Value='Computing parameters';
                drawnow
                app.paramcalc();
                app.StatusTextArea.Value='Idle';
            end
            t1=['C=' num2str(app.catr1) ', n=' num2str(app.atre1)];
            t2=['C=' num2str(app.catr2) ', n=' num2str(app.atre2)];
            t3=['C=' num2str(app.crep) ', n=' num2str(app.repe)];
            app.ShortrangeattractionTextArea.Value=t1;
            app.LongrangeattractionTextArea.Value=t2;
            app.RepulsionTextArea.Value=t3;
            app.LJenergyparameterTextArea.Value=[num2str(app.eps) ' kcal/mol'];
            dcutter=0.4*app.lc*2;
            if dcutter>2.5
                cputime=2.6*10^-7*(app.lc*2)^6*8;
            else
                cputime=2.6*10^-7*(app.lc*2)^6*4;
            end
            app.CPUTimeatomcalcTextArea.Value=[num2str(cputime) ' sec'];
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            value = app.MaterialDropDown.Value;
            if strcmp(value,'Silver')
                app.LJenergyparameterEditField.Value=0.2794;
                app.LJenergyparameterEditField.Value=0;
                app.Hamakerconstant10E19JEditField.Value=1.5;
                app.AtomicdiameternmEditField.Value=0.26326;
                app.Densitygcm3EditField.Value=10.49;
                app.AtomicweightDaEditField.Value=107.8672;
            elseif strcmp(value,'Copper')
                app.LJenergyparameterEditField.Value=0.4851;
                app.LJenergyparameterEditField.Value=0;
                app.Hamakerconstant10E19JEditField.Value=2.7;
                app.AtomicdiameternmEditField.Value=0.233;
                app.Densitygcm3EditField.Value=8.96;
                app.AtomicweightDaEditField.Value=63.55;
            elseif strcmp(value,'Silica')
                app.LJenergyparameterEditField.Value=0.0158;     
                app.LJenergyparameterEditField.Value=0;
                app.Hamakerconstant10E19JEditField.Value=0.76;
                app.AtomicdiameternmEditField.Value=0.54;
                app.Densitygcm3EditField.Value=2.65;
                app.AtomicweightDaEditField.Value=60.08;    
            elseif strcmp(value,'Gold')
                app.LJenergyparameterEditField.Value=0.4223;
                app.LJenergyparameterEditField.Value=0;
                app.Hamakerconstant10E19JEditField.Value=2.5;
                app.AtomicdiameternmEditField.Value=0.2629;
                app.Densitygcm3EditField.Value=19.3;
                app.AtomicweightDaEditField.Value=196.96657;                    
            end
        end

        % Value changed function: AtomicdiameternmEditField
        function AtomicdiameternmEditFieldValueChanged(app, event)
            value = app.AtomicdiameternmEditField.Value;
            app.sig=value;
        end

        % Value changed function: Hamakerconstant10E19JEditField
        function Hamakerconstant10E19JEditFieldValueChanged(app, event)
            value = app.Hamakerconstant10E19JEditField.Value;
            Ham=app.Hamakerconstant10E19JEditField.Value;

        end

        % Value changed function: CubesidelengthnmEditField
        function CubesidelengthnmEditFieldValueChanged(app, event)
            value = app.CubesidelengthnmEditField.Value;
            app.lc=value/2;
        end

        % Value changed function: slider_theta
        function slider_thetaValueChanged(app, event)
            value = app.slider_theta.Value;
            app.EditField_theta.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: slider_phi
        function slider_phiValueChanged(app, event)
            value = app.slider_phi.Value;
            app.EditField_phi.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: slider_psi
        function slider_psiValueChanged(app, event)
            value = app.slider_psi.Value;
            app.EditField_psi.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: slider_dz
        function slider_dzValueChanged(app, event)
            value = app.slider_dz.Value;
            app.EditField_dz.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: slider_dy
        function slider_dyValueChanged(app, event)
            value = app.slider_dy.Value;
            app.EditField_dy.Value=value;
            app.plot_cube(2);
        end

        % Value changed function: slider_ds
        function slider_dsValueChanged(app, event)
            value = app.slider_ds.Value;
            app.EditField_ds.Value=value;
            app.plot_cube(2);
        end

        % Button pushed function: ExportanalyticalresultButton
        function ExportanalyticalresultButtonPushed(app, event)
            app.calc_ver(2);
            app.StatusTextArea.Value='Idle';            
        end

        % Button pushed function: ExportatomisticresultButton
        function ExportatomisticresultButtonPushed(app, event)
            app.plot_atomenergy(2);
            app.StatusTextArea.Value='Idle';            
        end

        % Value changed function: EditField_ds
        function EditField_dsValueChanged(app, event)
            value = app.EditField_ds.Value;
            app.slider_ds.Value=value;
            app.plot_cube(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CubeconfigurationPanel = uipanel(app.UIFigure);
            app.CubeconfigurationPanel.Title = 'Cube configuration';
            app.thLabel = uilabel(app.CubeconfigurationPanel);
            app.thLabel.Text = 'th';
            app.slider_theta = uislider(app.CubeconfigurationPanel);
            app.slider_theta.Limits = [0 90];
            app.slider_theta.ValueChangedFcn = createCallbackFcn(app, @slider_thetaValueChanged, true);
            app.dsLabel = uilabel(app.CubeconfigurationPanel);
            app.dsLabel.Text = 'ds';
            app.slider_ds = uislider(app.CubeconfigurationPanel);
            app.slider_ds.Limits = [1 3];
            app.slider_ds.MajorTicks = [0.1 2.1 4.1 6.1 10];
            app.slider_ds.MajorTickLabels = {'0.1', '2.1', '4.1', '6.1', '10'};
            app.slider_ds.ValueChangedFcn = createCallbackFcn(app, @slider_dsValueChanged, true);
            app.slider_ds.MinorTicks = [];
            app.slider_ds.Value = 1;
            app.dyLabel = uilabel(app.CubeconfigurationPanel);
            app.dyLabel.Text = 'dy';
            app.slider_dy = uislider(app.CubeconfigurationPanel);
            app.slider_dy.Limits = [0 10];
            app.slider_dy.ValueChangedFcn = createCallbackFcn(app, @slider_dyValueChanged, true);
            app.slider_dy.Value = 5;
            app.dzLabel = uilabel(app.CubeconfigurationPanel);
            app.dzLabel.Text = 'dz';
            app.slider_dz = uislider(app.CubeconfigurationPanel);
            app.slider_dz.Limits = [0 10];
            app.slider_dz.ValueChangedFcn = createCallbackFcn(app, @slider_dzValueChanged, true);
            app.slider_dz.Value = 5;
            app.phLabel = uilabel(app.CubeconfigurationPanel);
            app.phLabel.Text = 'ph';
            app.slider_phi = uislider(app.CubeconfigurationPanel);
            app.slider_phi.Limits = [0 90];
            app.slider_phi.ValueChangedFcn = createCallbackFcn(app, @slider_phiValueChanged, true);
            app.psLabel = uilabel(app.CubeconfigurationPanel);
            app.psLabel.Text = 'ps';
            app.slider_psi = uislider(app.CubeconfigurationPanel);
            app.slider_psi.Limits = [0 90];
            app.slider_psi.ValueChangedFcn = createCallbackFcn(app, @slider_psiValueChanged, true);
            app.PositionLabel = uilabel(app.CubeconfigurationPanel);
            app.PositionLabel.Text = 'Position';
            app.OrientationLabel = uilabel(app.CubeconfigurationPanel);
            app.OrientationLabel.Text = 'Orientation';
            app.ComputeatomisticButton = uibutton(app.CubeconfigurationPanel, 'push');
            app.ComputeatomisticButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeatomisticButtonPushed, true);
            app.ComputeatomisticButton.Text = 'Compute atomistic';
            app.ComputeanalyticalButton = uibutton(app.CubeconfigurationPanel, 'push');
            app.ComputeanalyticalButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeanalyticalButtonPushed, true);
            app.ComputeanalyticalButton.Text = 'Compute analytical';
            app.EditField_phi = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.EditField_phi.ValueChangedFcn = createCallbackFcn(app, @EditField_phiValueChanged, true);
            app.EditField_theta = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.EditField_theta.ValueChangedFcn = createCallbackFcn(app, @EditField_thetaValueChanged, true);
            app.EditField_psi = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.EditField_psi.ValueChangedFcn = createCallbackFcn(app, @EditField_psiValueChanged, true);
            app.EditField_ds = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.EditField_ds.ValueChangedFcn = createCallbackFcn(app, @EditField_dsValueChanged, true);
            app.EditField_dy = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.EditField_dy.ValueChangedFcn = createCallbackFcn(app, @EditField_dyValueChanged, true);
            app.EditField_dy.Value = 5;
            app.EditField_dz = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.EditField_dz.ValueChangedFcn = createCallbackFcn(app, @EditField_dzValueChanged, true);
            app.EditField_dz.Value = 5;
            app.MaterialparametersLabel = uilabel(app.CubeconfigurationPanel);
            app.MaterialparametersLabel.Text = 'Material parameters';
            app.CubesidelengthnmEditFieldLabel = uilabel(app.CubeconfigurationPanel);
            app.CubesidelengthnmEditFieldLabel.Text = 'Cube side length (nm)';
            app.CubesidelengthnmEditField = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.CubesidelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @CubesidelengthnmEditFieldValueChanged, true);
            app.CubesidelengthnmEditField.Value = 10;
            app.Hamakerconstant10E19JEditFieldLabel = uilabel(app.CubeconfigurationPanel);
            app.Hamakerconstant10E19JEditFieldLabel.Text = 'Hamaker constant (10E-19J)';
            app.Hamakerconstant10E19JEditField = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.Hamakerconstant10E19JEditField.ValueChangedFcn = createCallbackFcn(app, @Hamakerconstant10E19JEditFieldValueChanged, true);
            app.Hamakerconstant10E19JEditField.Value = 1;
            app.EnterparametersButton = uibutton(app.CubeconfigurationPanel, 'push');
            app.EnterparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EnterparametersButtonPushed, true);
            app.EnterparametersButton.Text = 'Enter parameters';
            app.MaterialDropDownLabel = uilabel(app.CubeconfigurationPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.CubeconfigurationPanel);
            app.MaterialDropDown.Items = {'Silver', 'Gold', 'Copper', 'Silica', 'Custom'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Silver';
            app.AtomicdiameternmEditFieldLabel = uilabel(app.CubeconfigurationPanel);
            app.AtomicdiameternmEditFieldLabel.Text = 'Atomic diameter (nm)';
            app.AtomicdiameternmEditField = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.AtomicdiameternmEditField.ValueChangedFcn = createCallbackFcn(app, @AtomicdiameternmEditFieldValueChanged, true);
            app.AtomicdiameternmEditField.Value = 1;
            app.Densitygcm3EditFieldLabel = uilabel(app.CubeconfigurationPanel);
            app.Densitygcm3EditFieldLabel.Text = 'Density (g/cm3)';
            app.Densitygcm3EditField = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.Densitygcm3EditField.Value = 1;
            app.CODE_NAME = uidropdown(app.CubeconfigurationPanel);
            app.CODE_NAME.Items = {'Default', 'Recalibrate'};
            app.CODE_NAME.Value = 'Default';
            app.AtomicweightDaEditFieldLabel = uilabel(app.CubeconfigurationPanel);
            app.AtomicweightDaEditFieldLabel.Text = 'Atomic weight (Da)';
            app.AtomicweightDaEditField = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.AtomicweightDaEditField.Value = 1;
            app.LJenergyparameterkcalmolLabel = uilabel(app.CubeconfigurationPanel);
            app.LJenergyparameterkcalmolLabel.Text = 'LJ energy parameter (kcal/mol)';
            app.LJenergyparameterEditField = uieditfield(app.CubeconfigurationPanel, 'numeric');
            app.plot_cubevis = uiaxes(app.UIFigure);
            title(app.plot_cubevis, 'Title')
            xlabel(app.plot_cubevis, 'X')
            ylabel(app.plot_cubevis, 'Y')
            app.plot_energy = uiaxes(app.UIFigure);
            title(app.plot_energy, 'Interaction energy')
            xlabel(app.plot_energy, 'ds')
            ylabel(app.plot_energy, 'U')
            app.plot_face1 = uiaxes(app.UIFigure);
            title(app.plot_face1, 'Interaction energy')
            xlabel(app.plot_face1, 'y')
            ylabel(app.plot_face1, 'z')
            app.plot_face3 = uiaxes(app.UIFigure);
            title(app.plot_face3, 'Face')
            xlabel(app.plot_face3, 'y')
            ylabel(app.plot_face3, 'z')
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Value = {'Idle'};
            app.ExportanalyticalresultButton = uibutton(app.UIFigure, 'push');
            app.ExportanalyticalresultButton.ButtonPushedFcn = createCallbackFcn(app, @ExportanalyticalresultButtonPushed, true);
            app.ExportanalyticalresultButton.Text = 'Export analytical result';
            app.ExportatomisticresultButton = uibutton(app.UIFigure, 'push');
            app.ExportatomisticresultButton.ButtonPushedFcn = createCallbackFcn(app, @ExportatomisticresultButtonPushed, true);
            app.ExportatomisticresultButton.Text = 'Export atomistic result';
            app.ExportfilenameLabel = uilabel(app.UIFigure);
            app.ExportfilenameLabel.Text = 'Export file name:';
            app.ExportfilenameTextArea = uitextarea(app.UIFigure);
            app.ExportfilenameTextArea.Value = {'result.xls'};
            app.ShortrangeattractionLabel = uilabel(app.UIFigure);
            app.ShortrangeattractionLabel.Text = 'Short range attraction:';
            app.ShortrangeattractionTextArea = uitextarea(app.UIFigure);
            app.LongrangeattractionLabel = uilabel(app.UIFigure);
            app.LongrangeattractionLabel.Text = 'Long range attraction:';
            app.LongrangeattractionTextArea = uitextarea(app.UIFigure);
            app.RepulsionTextAreaLabel = uilabel(app.UIFigure);
            app.RepulsionTextAreaLabel.Text = 'Repulsion:';
            app.RepulsionTextArea = uitextarea(app.UIFigure);
            app.LJenergyparameterLabel = uilabel(app.UIFigure);
            app.LJenergyparameterLabel.Text = 'LJ energy parameter (ε):';
            app.LJenergyparameterTextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pos.png';
            app.CPUTimeatomcalcTextAreaLabel = uilabel(app.UIFigure);
            app.CPUTimeatomcalcTextAreaLabel.Text = 'CPU Time / atom calc:';
            app.CPUTimeatomcalcTextArea = uitextarea(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'orientation.png';
            app.PositionLabel_2 = uilabel(app.UIFigure);
            app.PositionLabel_2.Text = 'Position';
            app.OrientationLabel_2 = uilabel(app.UIFigure);
            app.OrientationLabel_2.Text = 'Orientation';
            app.ExportpathTextAreaLabel = uilabel(app.UIFigure);
            app.ExportpathTextAreaLabel.Text = 'Export path:';
            app.ExportpathTextArea = uitextarea(app.UIFigure);
            app.ExportpathTextArea.Value = {'C:\'};
            app.EnergycurrentconfigLabel = uilabel(app.UIFigure);
            app.EnergycurrentconfigLabel.Text = 'Energy @ current config:';
            app.EnergycurrentconfigTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nanocube_vdW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end